classdef rep_normalization_gui_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InputPanel                    matlab.ui.container.Panel
        InputbehaviorButtonGroup      matlab.ui.container.ButtonGroup
        DummyerfButton                matlab.ui.control.RadioButton
        DummytanhButton               matlab.ui.control.RadioButton
        DatafromfileButton            matlab.ui.control.RadioButton
        UIAxes_InputFunction          matlab.ui.control.UIAxes
        RuninputButton                matlab.ui.control.Button
        FilePanel                     matlab.ui.container.Panel
        CorrFactorEditFieldLabel      matlab.ui.control.Label
        CorrFactorEditField           matlab.ui.control.NumericEditField
        FilenameTextAreaLabel         matlab.ui.control.Label
        FilenameTextArea              matlab.ui.control.TextArea
        DimensionalityButtonGroup     matlab.ui.container.ButtonGroup
        DButton                       matlab.ui.control.RadioButton
        DButton_2                     matlab.ui.control.RadioButton
        DButton_3                     matlab.ui.control.RadioButton
        GOFRMSEEditFieldLabel         matlab.ui.control.Label
        GOFRMSEEditField              matlab.ui.control.NumericEditField
        FunctionparametersPanel       matlab.ui.container.Panel
        FunctionChosenLabel           matlab.ui.control.Label
        aEditFieldLabel               matlab.ui.control.Label
        aEditField                    matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        bEditField                    matlab.ui.control.NumericEditField
        cEditFieldLabel               matlab.ui.control.Label
        cEditField                    matlab.ui.control.NumericEditField
        dEditFieldLabel               matlab.ui.control.Label
        dEditField                    matlab.ui.control.NumericEditField
        NormalizationPanel            matlab.ui.container.Panel
        NormalizeButton               matlab.ui.control.Button
        ModelkinematicsButtonGroup    matlab.ui.container.ButtonGroup
        LinearButton                  matlab.ui.control.RadioButton
        NonlinearButton               matlab.ui.control.RadioButton
        ReferencestateButtonGroup     matlab.ui.container.ButtonGroup
        InflexionpointButton          matlab.ui.control.RadioButton
        ChooseButton                  matlab.ui.control.RadioButton
        RefEditFieldLabel             matlab.ui.control.Label
        Reference_Value_entered       matlab.ui.control.NumericEditField
        NormalizestimulusaxisButtonGroup  matlab.ui.container.ButtonGroup
        YesButton                     matlab.ui.control.RadioButton
        NoButton                      matlab.ui.control.RadioButton
        UIAxes2_ReferenceState        matlab.ui.control.UIAxes
        UIAxes3_NormalizedBehavior    matlab.ui.control.UIAxes
        UIAxes4_ExpansionCoefficient  matlab.ui.control.UIAxes
        OutputPanel                   matlab.ui.container.Panel
        OutputButton                  matlab.ui.control.Button
        OutputfileButtonGroup         matlab.ui.container.ButtonGroup
        StandardnameButton            matlab.ui.control.RadioButton
        ChooseButton_2                matlab.ui.control.RadioButton
        ValueorderinthefilePanel      matlab.ui.container.Panel
        stvalueDropDownLabel          matlab.ui.control.Label
        stvalueDropDown               matlab.ui.control.DropDown
        ndvalueDropDownLabel          matlab.ui.control.Label
        ndvalueDropDown               matlab.ui.control.DropDown
        FilenamePanel                 matlab.ui.container.Panel
        NameEditFieldLabel            matlab.ui.control.Label
        NameEditField                 matlab.ui.control.EditField
    end

    
    properties (Access = public)
        swelling_function % The result of the input section is a swelling curve
        stimulus % The stimulus definition in accordance to the swelling curve
        expansion_coefficient % The expansion coefficient, where the reference value is removed to avoid the NaN
        stimulus_new % Reduced stimulus, adapted to the expansion_coefficient
        stimulus_reference % Reference value for the normalization
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RuninputButton
        function RuninputButtonPushed(app, event)
            
            % Make next stage available
            app.NormalizeButton.Enable = true;
            clc;


            % Switch case according to Buttons, using Dummy functions or reading in a file
            if app.DummyerfButton.Value == true
                disp 'Using erf function'
                syms x a b c d;
                sigmoid_erf(x,a,b,c,d) = a*erf(b*(x-c))+d; % diff(a*erf(b*(x+c)));
                stretch = app.aEditField.Value;
                slope = app.bEditField.Value;
                rp = app.cEditField.Value; % Referenzpunkt kann direkt hier reingegeben werden, weil eigentlich der Nullpunkt Umkehrpunkt ist, mit "x-rp" wird er nach rechts verschoben
                ordinate = app.dEditField.Value;
                % erf
                sigmoid_fkt_erf(x) = subs(sigmoid_erf, [a b c d], [stretch slope rp ordinate]);
                fplot(app.UIAxes_InputFunction, sigmoid_fkt_erf, [0, 2*rp], 'r');
                app.UIAxes_InputFunction.Title.String = 'a*erf(b*(x-c))+d';
                
                % Defined function for next steps
                app.stimulus = 0:0.1:2*rp;
                app.swelling_function = sigmoid_fkt_erf;


            elseif app.DummytanhButton.Value == true
                disp 'Using tanh function'
                syms x a b c d;
                sigmoid_tanh(x,a,b,c,d) = a*tanh(b*(x-c))+d; % diff(a*erf(b*(x+c)));
                stretch = app.aEditField.Value;
                slope = app.bEditField.Value;
                rp = app.cEditField.Value; % Referenzpunkt kann direkt hier reingegeben werden, weil eigentlich der Nullpunkt Umkehrpunkt ist, mit "x-rp" wird er nach rechts verschoben
                ordinate = app.dEditField.Value;
                % tanh
                sigmoid_tanh(x,a,b,c,d) = a*tanh(b*(x-c))+d;
                sigmoid_fkt_tanh(x) = subs(sigmoid_tanh, [a b c d], [stretch slope rp ordinate]);
                fplot(app.UIAxes_InputFunction, sigmoid_fkt_tanh, [0, 2*rp], 'b');
                app.UIAxes_InputFunction.Title.String = 'a*tanh(b*(x-c))+d';


                % Defined function for next steps
                app.stimulus = 0:0.1:2*rp;
                app.swelling_function = sigmoid_fkt_tanh;
                
                
            else
                disp 'Choosing file for loading'
                %try
                % Load a file with the UI
                [fileName, pathName] = uigetfile('./rep_normalization_input/*.*');
                fullFileName = fullfile(pathName, fileName);
                
                app.FilenameTextArea.Enable = 'on';
                app.FilenameTextArea.Value = fullFileName;
                
                data = importdata(fullFileName);
                
                % Stimulus
                app.stimulus = data.data(:,1);
                
                
                % Swelling curve
                if app.DButton.Value == true
                    disp 'Data provided as diameter.'
                    diameter = data.data(:,3)/app.CorrFactorEditField.Value;
                elseif app.DButton_2.Value == true
                    disp 'Data provided as an area. To gain an equivalent diameter, the square root is taken.'
                    diameter = (  data.data(:,3)/app.CorrFactorEditField.Value  ).^(1/2);
                else
                    disp 'Data provided as a volume. To gain an equivalent diameter, the third root is taken.'
                    diameter = (  data.data(:,3)/app.CorrFactorEditField.Value  ).^(1/3);
                end
                
                % Plot the points
                plot(app.UIAxes_InputFunction, app.stimulus, diameter, 'xb');
                app.UIAxes_InputFunction.Title.Interpreter = 'none';
                app.UIAxes_InputFunction.Title.String = strcat('Read from file: ', fileName);
                
                
                % Interpolate a spline
                [xData, yData] = prepareCurveData( app.stimulus, diameter ); % Vorbereitung der Daten
                
                % Vorbereitung Fittyp und Optionen
                ft = fittype( 'smoothingspline' ); % Spline
                opts = fitoptions( 'Method', 'SmoothingSpline' );
                opts.SmoothingParam = 0.445417778225271;
                [fitResult, gof] = fit( xData, yData, ft, opts );
                app.GOFRMSEEditField.Value = gof.rmse;
                
                %fitted_swelling_function = @(x) fitResult(x);   % Declaration as an anonymous function
                x_rastering = xData(1):(xData(end)-xData(1))/1000:xData(end);
                fitted_swelling_function = feval(fitResult, x_rastering);
                
                % Plot the interpolated function
                hold(app.UIAxes_InputFunction, 'on');
                plot(app.UIAxes_InputFunction, x_rastering, fitted_swelling_function', 'k');
                hold(app.UIAxes_InputFunction, 'off');
                
                % Defined function for next steps
                app.stimulus = data.data(:,1);
                app.swelling_function = fitResult;
                
                
            end


        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            % Make next stage available
            app.OutputButton.Enable = true;
            
            % Run normalization
            
            % First: Define the reference point
            if app.InflexionpointButton.Value == true
                disp 'Reference point is the inflexion point'
                % here, input the script to find the inflexion point
                [S_ref, swelling_ref] = rep_normalization_reference_states(app.stimulus, app.swelling_function);
                
            elseif app.ChooseButton.Value == true
                S_ref = app.Reference_Value_entered.Value;
                % Check, if it is like expected
                try
                    if S_ref > app.stimulus(end); error 'Ref is bigger than maximum stimulus'; end
                    if S_ref < app.stimulus(1); error 'Ref is smaller than minimum stimulus'; end
                catch
                    errordlg 'Value for Ref is not as expected: Is it a number? Is the decimal-dot correctly entered? Is the reference value in the stimulus range?'
                end
                %
                % Get the reference value of the swelling curve in accordance to the reference point
                swelling_ref = feval(app.swelling_function, S_ref);
                
            end


            % Now, there is a reference point for the normalization
            
            % Convert the fitting function into an anonymous function for further processing
            if isequal(class(app.swelling_function),'cfit')
                x_rastering = app.stimulus(1):(app.stimulus(end)-app.stimulus(1))/1000:app.stimulus(end);
                swelling_function_rasterized = feval(app.swelling_function, x_rastering);
                app.stimulus = x_rastering';
            elseif isequal(class(app.swelling_function),'symfun')
                x_rastering = app.stimulus(1):(app.stimulus(end)-app.stimulus(1))/1000:app.stimulus(end);
                swelling_function_rasterized = double(app.swelling_function(x_rastering))';
                app.stimulus = x_rastering';
            end
            
            
            % Define alpha or beta coefficient, depending on linear or nonlinear model
            if app.LinearButton.Value == true
                % Linear kinematics and alpha value
                epsilon = (swelling_function_rasterized - swelling_ref)/swelling_ref;
                epsilon_name = 'Engineering strain \epsilon';
                
                % Alpha is the linear stimulus expansion coefficient
                expansion_measure_name = 'Alpha';
                
                
            elseif app.NonlinearButton.Value == true
                % Nonlinear kinematics and beta value
                epsilon = log(swelling_function_rasterized/swelling_ref);
                epsilon_name = 'Hencky strain \epsilon^H';
                % Remove the NaN at the reference point
                
                % Beta is the nonlinear stimulus expansion coefficient
                expansion_measure_name = 'Beta';
                
            else
                errordlg 'Broken out of loop'
            end


            % Use the stimulus or equivalent stimulus?
            if app.YesButton.Value == true
                stimulus_axis = app.stimulus/S_ref;
                stimulus_axis_name = 'F^S';
                
                
                % Expansion coefficient with the correct measure
                %if isequal(class(app.swelling_function),'double')
                expansion_measure = epsilon./(stimulus_axis-1);
                %else
                %    expansion_measure = double(epsilon(app.stimulus))./(app.stimulus-1);
                %end


                % Remove the NaN at the reference point
                try
                    expansion_measure(stimulus_axis==1) = [];
                    stimulus_axis_reduced = stimulus_axis(stimulus_axis~=1);
                    disp 'NaN at reference point removed'
                catch
                    disp 'No value at the reference point'
                end
                
            else % Use physical stimulus values instead
                stimulus_axis = app.stimulus;
                stimulus_axis_name = 'S';
                
                % Expansion coefficient with the correct measure
                %if isequal(class(app.swelling_function),'double')
                expansion_measure = epsilon./(stimulus_axis-S_ref);
                %else
                %    expansion_measure = double(epsilon(app.stimulus))./(app.stimulus-S_ref);
                %end
                
                % Remove the NaN at the reference point
                try
                    expansion_measure(stimulus_axis==S_ref) = [];
                    stimulus_axis_reduced = stimulus_axis(stimulus_axis~=S_ref);
                    disp 'NaN at reference point removed'
                catch
                    disp 'No value at the reference point'
                end
            end


            % Now, we have the engineering strain or Hencky strain over the
            % stimulus S or normalized stimulus ratio F^S
            % Plots
            plot(app.UIAxes2_ReferenceState, app.stimulus, swelling_function_rasterized, 'b');
            hold(app.UIAxes2_ReferenceState, 'on');
            app.UIAxes2_ReferenceState.XLabel.String = 'Physical stimulus';
            app.UIAxes2_ReferenceState.YLabel.String = 'Swelling curve';
            plot(app.UIAxes2_ReferenceState, S_ref, swelling_ref, 'r*');
            hold(app.UIAxes2_ReferenceState, 'off');
            
            plot(app.UIAxes3_NormalizedBehavior, stimulus_axis, epsilon, 'b');
            %hold(app.UIAxes3_NormalizedBehavior, 'on');
            %app.UIAxes2_ReferenceState.XLabel.Interpreter = 'latex';
            app.UIAxes3_NormalizedBehavior.XLabel.String = stimulus_axis_name;
            %app.UIAxes2_ReferenceState.YLabel.Interpreter = 'latex';
            app.UIAxes3_NormalizedBehavior.YLabel.String = epsilon_name;
            %plot(app.UIAxes2_ReferenceState, S_ref, swelling_ref, 'r*');
            
            % Plot
            plot(app.UIAxes4_ExpansionCoefficient, stimulus_axis_reduced, expansion_measure, 'b');
            app.UIAxes4_ExpansionCoefficient.XLabel.String = stimulus_axis_name;
            app.UIAxes4_ExpansionCoefficient.YLabel.String = expansion_measure_name;
            
            % Output values of this function that are printed to a file in the next section
            app.expansion_coefficient = expansion_measure;
            app.stimulus_new = stimulus_axis_reduced;
            app.stimulus_reference = S_ref;
            
        end

        % Selection changed function: InputbehaviorButtonGroup
        function InputbehaviorButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputbehaviorButtonGroup.SelectedObject;
            
            if isequal(selectedButton.Text,'Data from file')
                disp 'Choosing Data from file'
                app.FilePanel.Visible = 'on';
                app.FunctionparametersPanel.Visible = 'off';
                
            elseif isequal(selectedButton.Text,'Dummy erf')
                disp 'Choosing predefined function'
                app.FilePanel.Visible = 'off';
                app.FunctionparametersPanel.Visible = 'on';
                app.FunctionChosenLabel.Text = 'a*erf(b*(x-c))+d';
                
            elseif isequal(selectedButton.Text,'Dummy tanh')
                disp 'Choosing predefined function'
                app.FilePanel.Visible = 'off';
                app.FunctionparametersPanel.Visible = 'on';
                app.FunctionChosenLabel.Text = 'a*tanh(b*(x-c))+d';
                
            end
            
            
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            % Writes the data to a file in a chosen format
            
            
            % Construct file name or take the chosen one
            if isequal(app.StandardnameButton.Value, 1)
                % File naming scheme: timestamp_Srefdefinition_normalizedaxis_linearity
                filenamecomponents{1} = datestr(datetime('now'), 'yyyymmdd_HHMMSS');
                if app.LinearButton.Value == 1; filenamecomponents{2}='_linear'; else filenamecomponents{2}='_nonlinear'; end
                
                if app.InflexionpointButton.Value == 1
                    filenamecomponents{3}=strcat('_inflexion_point_Sref=', num2str(app.stimulus_reference));
                else
                    filenamecomponents{3}=strcat('_chosen_Sref=', num2str(app.Reference_Value_entered.Value));
                end
                
                if app.YesButton.Value == 1; filenamecomponents{4}='_axis_normalized'; else filenamecomponents{4}='_axis_nonnormalized'; end
                % Concatenate file name
                filename = strcat(filenamecomponents{1:end}) ;
            elseif isequal(app.ChooseButton.Value, 1)
                if isempty(app.NameEditField.Value)
                    filename = 'none';
                else
                    filename = app.NameEditField.Value;
                end
                
            end
            
            
            % Choose the correct values to add to the file
            if isequal(app.stvalueDropDown.Value, 'Expansion coefficient')
                file_value1 = app.expansion_coefficient;
            elseif isequal(app.stvalueDropDown.Value, 'Stimulus')
                file_value1 = app.stimulus_new;
            end
            
            if isequal(app.ndvalueDropDown.Value, 'Expansion coefficient')
                file_value1 = app.expansion_coefficient;
            elseif isequal(app.ndvalueDropDown.Value, 'Stimulus')
                file_value2 = app.stimulus_new;
            end
            
            % Writing process
            filename_full = sprintf('./rep_normalization_output/%s', filename);
            file = fopen(filename_full, 'w');
            fprintf(file, '%f %f \n', [file_value1 file_value2]');
            fclose(file);
        end

        % Selection changed function: OutputfileButtonGroup
        function OutputfileButtonGroupSelectionChanged(app, event)
            selectedButton = app.OutputfileButtonGroup.SelectedObject;
            if isequal(selectedButton.Text,'Standard Name')
                app.NameEditField.Enable = 'off';
                app.NameEditField.Editable = 'off';
            else
                app.NameEditField.Enable = 'on';
                app.NameEditField.Editable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.InputbehaviorButtonGroup = uibuttongroup(app.InputPanel);
            app.InputbehaviorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputbehaviorButtonGroupSelectionChanged, true);
            app.InputbehaviorButtonGroup.Title = 'Input behavior';
            app.DummyerfButton = uiradiobutton(app.InputbehaviorButtonGroup);
            app.DummyerfButton.Text = 'Dummy erf';
            app.DummyerfButton.Value = true;
            app.DummytanhButton = uiradiobutton(app.InputbehaviorButtonGroup);
            app.DummytanhButton.Text = 'Dummy tanh';
            app.DatafromfileButton = uiradiobutton(app.InputbehaviorButtonGroup);
            app.DatafromfileButton.Text = 'Data from file';
            app.UIAxes_InputFunction = uiaxes(app.InputPanel);
            title(app.UIAxes_InputFunction, 'Input function')
            xlabel(app.UIAxes_InputFunction, 'Stimulus')
            ylabel(app.UIAxes_InputFunction, 'Sphere equivalent diameter')
            app.RuninputButton = uibutton(app.InputPanel, 'push');
            app.RuninputButton.ButtonPushedFcn = createCallbackFcn(app, @RuninputButtonPushed, true);
            app.RuninputButton.Text = 'Run input';
            app.FilePanel = uipanel(app.InputPanel);
            app.FilePanel.Title = 'File';
            app.FilePanel.Visible = 'off';
            app.CorrFactorEditFieldLabel = uilabel(app.FilePanel);
            app.CorrFactorEditFieldLabel.Text = 'Corr. Factor';
            app.CorrFactorEditField = uieditfield(app.FilePanel, 'numeric');
            app.CorrFactorEditField.Value = 1;
            app.FilenameTextAreaLabel = uilabel(app.FilePanel);
            app.FilenameTextAreaLabel.Text = 'Filename';
            app.FilenameTextArea = uitextarea(app.FilePanel);
            app.FilenameTextArea.Editable = 'off';
            app.FilenameTextArea.Enable = 'off';
            app.FilenameTextArea.Value = {'none'};
            app.DimensionalityButtonGroup = uibuttongroup(app.FilePanel);
            app.DimensionalityButtonGroup.Tooltip = {'Choose, if the data provided is 1D (e.g., a diameter or height), 2D (a measured area) or 3D (a volume, e.g. gained from the mass)'};
            app.DimensionalityButtonGroup.Title = 'Dimensionality';
            app.DButton = uiradiobutton(app.DimensionalityButtonGroup);
            app.DButton.Text = '1D';
            app.DButton.Value = true;
            app.DButton_2 = uiradiobutton(app.DimensionalityButtonGroup);
            app.DButton_2.Text = '2D';
            app.DButton_3 = uiradiobutton(app.DimensionalityButtonGroup);
            app.DButton_3.Text = '3D';
            app.GOFRMSEEditFieldLabel = uilabel(app.FilePanel);
            app.GOFRMSEEditFieldLabel.Enable = 'off';
            app.GOFRMSEEditFieldLabel.Text = 'GOF RMSE';
            app.GOFRMSEEditField = uieditfield(app.FilePanel, 'numeric');
            app.GOFRMSEEditField.Editable = 'off';
            app.GOFRMSEEditField.Enable = 'off';
            app.GOFRMSEEditField.Tooltip = {'Returns the goodness of fit value for the fitted function. '};
            app.FunctionparametersPanel = uipanel(app.InputPanel);
            app.FunctionparametersPanel.Title = 'Function parameters';
            app.FunctionChosenLabel = uilabel(app.FunctionparametersPanel);
            app.FunctionChosenLabel.Text = 'a*erf(b*(x-c))+d';
            app.aEditFieldLabel = uilabel(app.FunctionparametersPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.FunctionparametersPanel, 'numeric');
            app.aEditField.Tooltip = {'a is the stretch of the function'};
            app.aEditField.Value = 0.5;
            app.bEditFieldLabel = uilabel(app.FunctionparametersPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.FunctionparametersPanel, 'numeric');
            app.bEditField.Tooltip = {'b represents the slope at the inflexion point'};
            app.bEditField.Value = 4;
            app.cEditFieldLabel = uilabel(app.FunctionparametersPanel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.FunctionparametersPanel, 'numeric');
            app.cEditField.Tooltip = {'c defines the position of the inflexion point'};
            app.cEditField.Value = 2;
            app.dEditFieldLabel = uilabel(app.FunctionparametersPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.FunctionparametersPanel, 'numeric');
            app.dEditField.Tooltip = {'d is the ordinate, i.e., the y-displacement of the curve'};
            app.dEditField.Value = 1;
            app.NormalizationPanel = uipanel(app.UIFigure);
            app.NormalizationPanel.Title = 'Normalization';
            app.NormalizeButton = uibutton(app.NormalizationPanel, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Enable = 'off';
            app.NormalizeButton.Text = 'Normalize';
            app.ModelkinematicsButtonGroup = uibuttongroup(app.NormalizationPanel);
            app.ModelkinematicsButtonGroup.Title = 'Model kinematics';
            app.LinearButton = uiradiobutton(app.ModelkinematicsButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.NonlinearButton = uiradiobutton(app.ModelkinematicsButtonGroup);
            app.NonlinearButton.Text = 'Nonlinear';
            app.ReferencestateButtonGroup = uibuttongroup(app.NormalizationPanel);
            app.ReferencestateButtonGroup.Title = 'Reference state';
            app.InflexionpointButton = uiradiobutton(app.ReferencestateButtonGroup);
            app.InflexionpointButton.Text = 'Inflexion point';
            app.InflexionpointButton.Value = true;
            app.ChooseButton = uiradiobutton(app.ReferencestateButtonGroup);
            app.ChooseButton.Text = 'Choose';
            app.RefEditFieldLabel = uilabel(app.ReferencestateButtonGroup);
            app.RefEditFieldLabel.Text = 'Ref';
            app.Reference_Value_entered = uieditfield(app.ReferencestateButtonGroup, 'numeric');
            app.Reference_Value_entered.Value = 1;
            app.NormalizestimulusaxisButtonGroup = uibuttongroup(app.NormalizationPanel);
            app.NormalizestimulusaxisButtonGroup.Title = 'Normalize stimulus axis?';
            app.YesButton = uiradiobutton(app.NormalizestimulusaxisButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.NormalizestimulusaxisButtonGroup);
            app.NoButton.Text = 'No';
            app.UIAxes2_ReferenceState = uiaxes(app.NormalizationPanel);
            title(app.UIAxes2_ReferenceState, 'Reference state')
            xlabel(app.UIAxes2_ReferenceState, 'Stimulus')
            ylabel(app.UIAxes2_ReferenceState, 'Diameter')
            app.UIAxes3_NormalizedBehavior = uiaxes(app.NormalizationPanel);
            title(app.UIAxes3_NormalizedBehavior, 'Normalized behavior')
            xlabel(app.UIAxes3_NormalizedBehavior, 'Stimulus')
            ylabel(app.UIAxes3_NormalizedBehavior, 'Strain measure')
            app.UIAxes4_ExpansionCoefficient = uiaxes(app.NormalizationPanel);
            title(app.UIAxes4_ExpansionCoefficient, 'Expansion cofficient')
            xlabel(app.UIAxes4_ExpansionCoefficient, 'Stimulus')
            ylabel(app.UIAxes4_ExpansionCoefficient, 'Expansion coefficient')
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.OutputButton = uibutton(app.OutputPanel, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Enable = 'off';
            app.OutputButton.Text = 'Output';
            app.OutputfileButtonGroup = uibuttongroup(app.OutputPanel);
            app.OutputfileButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OutputfileButtonGroupSelectionChanged, true);
            app.OutputfileButtonGroup.Title = 'Output file';
            app.StandardnameButton = uiradiobutton(app.OutputfileButtonGroup);
            app.StandardnameButton.Text = 'Standard name';
            app.StandardnameButton.Value = true;
            app.ChooseButton_2 = uiradiobutton(app.OutputfileButtonGroup);
            app.ChooseButton_2.Text = 'Choose';
            app.ValueorderinthefilePanel = uipanel(app.OutputPanel);
            app.ValueorderinthefilePanel.Title = 'Value order in the file';
            app.stvalueDropDownLabel = uilabel(app.ValueorderinthefilePanel);
            app.stvalueDropDownLabel.Text = '1st value';
            app.stvalueDropDown = uidropdown(app.ValueorderinthefilePanel);
            app.stvalueDropDown.Items = {'Stimulus', 'Expansion coefficient'};
            app.stvalueDropDown.Value = 'Expansion coefficient';
            app.ndvalueDropDownLabel = uilabel(app.ValueorderinthefilePanel);
            app.ndvalueDropDownLabel.Text = '2nd value';
            app.ndvalueDropDown = uidropdown(app.ValueorderinthefilePanel);
            app.ndvalueDropDown.Items = {'Stimulus', 'Expansion coefficient'};
            app.ndvalueDropDown.Value = 'Stimulus';
            app.FilenamePanel = uipanel(app.OutputPanel);
            app.FilenamePanel.Title = 'File name';
            app.NameEditFieldLabel = uilabel(app.FilenamePanel);
            app.NameEditFieldLabel.Enable = 'off';
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.FilenamePanel, 'text');
            app.NameEditField.Editable = 'off';
            app.NameEditField.Enable = 'off';
            app.NameEditField.Value = 'none';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rep_normalization_gui_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end